classdef app4_UI_Prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        PushTool2                     matlab.ui.container.toolbar.PushTool
        PushTool3                     matlab.ui.container.toolbar.PushTool
        Pointer                       matlab.ui.container.toolbar.ToggleTool
        Target                        matlab.ui.container.toolbar.ToggleTool
        Obstacle                      matlab.ui.container.toolbar.ToggleTool
        Eraser                        matlab.ui.container.toolbar.ToggleTool
        Run                           matlab.ui.container.toolbar.PushTool
        PushTool5                     matlab.ui.container.toolbar.PushTool
        PushTool4                     matlab.ui.container.toolbar.PushTool
        Pan                           matlab.ui.container.toolbar.ToggleTool
        ZoomIn                        matlab.ui.container.toolbar.ToggleTool
        ZoomOut                       matlab.ui.container.toolbar.ToggleTool
        RestoreView                   matlab.ui.container.toolbar.PushTool
        GridLayout                    matlab.ui.container.GridLayout
        Tree2                         matlab.ui.container.Tree
        TabGroup2                     matlab.ui.container.TabGroup
        RobotParametersTab            matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        firstangleEditField           matlab.ui.control.NumericEditField
        firstangleEditFieldLabel      matlab.ui.control.Label
        maxlengthEditFieldLabel       matlab.ui.control.Label
        maxlengthEditField            matlab.ui.control.NumericEditField
        minlengthEditFieldLabel       matlab.ui.control.Label
        minlengthEditField            matlab.ui.control.NumericEditField
        maxangleEditField             matlab.ui.control.NumericEditField
        maxangleEditFieldLabel        matlab.ui.control.Label
        minangleEditField             matlab.ui.control.NumericEditField
        minangleEditFieldLabel        matlab.ui.control.Label
        linksEditField                matlab.ui.control.NumericEditField
        linksEditFieldLabel           matlab.ui.control.Label
        fixedfirstangleCheckBox       matlab.ui.control.CheckBox
        OptimizationAlgorithmTab      matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        mutationmethodDropDownLabel   matlab.ui.control.Label
        mutationmethodDropDown        matlab.ui.control.DropDown
        crossoverprobabilitySpinnerLabel  matlab.ui.control.Label
        crossoverprobabilitySpinner   matlab.ui.control.Spinner
        crossovermethodDropDownLabel  matlab.ui.control.Label
        crossovermethodDropDown       matlab.ui.control.DropDown
        crunchmethodDropDownLabel     matlab.ui.control.Label
        crunchmethodDropDown          matlab.ui.control.DropDown
        penaltymethodDropDownLabel    matlab.ui.control.Label
        penaltymethodDropDown         matlab.ui.control.DropDown
        individualsEditFieldLabel     matlab.ui.control.Label
        individualsEditField          matlab.ui.control.NumericEditField
        generationsEditFieldLabel     matlab.ui.control.Label
        generationsEditField          matlab.ui.control.NumericEditField
        algorithmDropDown             matlab.ui.control.DropDown
        algorithmDropDownLabel        matlab.ui.control.Label
        survivalalphaEditField        matlab.ui.control.NumericEditField
        survivalalphaEditFieldLabel   matlab.ui.control.Label
        survivalmethodDropDown        matlab.ui.control.DropDown
        survivalmethodDropDownLabel   matlab.ui.control.Label
        mutationprobabilitySpinner    matlab.ui.control.Spinner
        mutationprobabilitySpinnerLabel  matlab.ui.control.Label
        slectionmethodDropDown        matlab.ui.control.DropDown
        slectionmethodDropDownLabel   matlab.ui.control.Label
        obstacleavoidanceCheckBox     matlab.ui.control.CheckBox
        RankAlgorithmTab              matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        stepIKEditFieldLabel          matlab.ui.control.Label
        stepIKEditField               matlab.ui.control.NumericEditField
        steplengthEditField           matlab.ui.control.NumericEditField
        steplengthEditFieldLabel      matlab.ui.control.Label
        methodDropDown                matlab.ui.control.DropDown
        methodDropDownLabel           matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        PropertiesTab                 matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        angledegEditField             matlab.ui.control.NumericEditField
        angledegEditFieldLabel        matlab.ui.control.Label
        lengthEditField               matlab.ui.control.NumericEditField
        lengthEditFieldLabel          matlab.ui.control.Label
        ycoordinateEditField          matlab.ui.control.NumericEditField
        ycoordinateEditFieldLabel     matlab.ui.control.Label
        xcoordinateEditField          matlab.ui.control.NumericEditField
        xcoordinateEditFieldLabel     matlab.ui.control.Label
        Tree                          matlab.ui.container.Tree
        BaseNode                      matlab.ui.container.TreeNode
        TargetsNode                   matlab.ui.container.TreeNode
        ObstaclesNode                 matlab.ui.container.TreeNode
        ObjectsLabel                  matlab.ui.control.Label
        OptionsLabel                  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        op % Description
        gas
        
        bbbcs
        algorithm
        stopRunItGeneric % Description

        nChar % Description
        tabChar ;
    end
    
    properties (Access = private)
        XLim = [-50 200];
            
        YLim = [-50 200]; % Description % Description
        loadedData % Description
    end
    
    methods (Access = private)
        
        function ps = draw_obstacle(app,x,y,radius)
            % Draws a circle 
            % Returns the cricle

            circle = polyshape(cosd(0:1:360) * radius + x, sind(0:1:360) * radius + y);
            ps = plot(app.UIAxes,circle,'LineWidth',1); % 'b' for blue outline
        
        end


        function ps = draw_target(app,x,y,deg)
        star = [
                    cosd(90)+x sind(90)+y
                    (1/3)*cosd(54)+x (1/3)*sind(54)+y
                    cosd(18)+x sind(18)+y
                    (1/3)*cosd(342)+x (1/3)*sind(342)+y
                    cosd(306)+x sind(306)+y
                    x -1/3+y
                    cosd(234)+x sind(234)+y
                    (1/3)*cosd(198)+x (1/3)*sind(198)+y
                    cosd(162)+x sind(162)+y
                    (1/3)*cosd(126)+x (1/3)*sind(126)+y
                    cosd(90)+x sind(90)+y

                    ];
            shape = polyshape(star);
            shape = rotate(shape,deg,[x y ]);
            ps = plot(app.UIAxes,shape,'LineWidth',1);
            
        end
        function ps = draw_base(app,x,y,deg)
            % Draws a rectangle
            % Returns the rectangle
            theta=deg*pi/180;
            H = 1;
            L = H;
            center_location = [ x y];
            center1=center_location(1);
            center2=center_location(2);
            
            R= ([cos(theta), -sin(theta); sin(theta), cos(theta)]);
            
            X=([-L/2, L/2, L/2, -L/2]);
            Y=([-H/2, -H/2, H/2, H/2]);

            T = zeros(2,4);
            for i=1:4
            T(:,i)=R*[X(i); Y(i)];
            end
            
            x_lower_left=center1+T(1,1);
            x_lower_right=center1+T(1,2);
            x_upper_right=center1+T(1,3);
            x_upper_left=center1+T(1,4);
            
            y_lower_left=center2+T(2,1);
            y_lower_right=center2+T(2,2);
            y_upper_right=center2+T(2,3);
            y_upper_left=center2+T(2,4);
            
            x_coor=[x_upper_left x_lower_left x_lower_right x_upper_right ];
            y_coor=[y_upper_left y_lower_left y_lower_right y_upper_right ];
            shape = polyshape(x_coor,y_coor);
            ps = plot(app.UIAxes,shape,'LineWidth',1,"FaceColor",'#001aff', 'FaceAlpha',1);
            
        end


        function ps_mouse_click(app,src)  
            if src.Selected == "off"
                app.Tree.SelectedNodes = src.UserData;
                node_click(app);
            else
                disable_properties(app);
                src.Selected = "off";
            end
            if app.Eraser.State == "on"
                if app.Tree.SelectedNodes.Parent == app.ObstaclesNode || app.Tree.SelectedNodes.Parent == app.TargetsNode
                deleteSelected(app);
                
                end
            end
            

        end


        function select_child(app,child)
            children = app.UIAxes.Children;
            for i = 1:length(children)
                if child ~= children(i)
                    children(i).Selected = "off";
                end
            end
            child.Selected = "on";
        end


        function node = add_obstacle_node(app,ps,x,y,radius)

            data = struct('child', ps, 'x', x, 'y', y,'Radius',radius);
            text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", r: " + string(round(radius,2));
            node = uitreenode(app.ObstaclesNode,"NodeData",data,"Text",text);
            
            % msgbox(string(app.ObstaclesNode.Children(1).NodeData.x))
        end


        function node = add_target_node(app,ps,x,y,L,deg)

            data = struct('child', ps, 'x', x, 'y', y,'L',L,'deg',deg);
            text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", deg: " + string(round(deg,2));
            node =  uitreenode(app.TargetsNode,"NodeData",data,"Text",text);
            % msgbox(string(app.ObstaclesNode.Children(1).NodeData.x))
        end


        function node = add_base_node(app,src,x,y,deg)
            data = struct('child', src, 'x', x, 'y', y,'deg',deg);
            text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", deg: " + string(round(deg,2));
            node =  uitreenode(app.BaseNode,"NodeData",data,"Text",text);
        end


        function node_click(app)
            
            selectedNodes = app.Tree.SelectedNodes;
            if app.ZoomOut.UserData == "eraser"
                % when erasing, if you click on a node, it selects a child
                select_child(app,selectedNodes.NodeData.child);

            elseif selectedNodes.Parent == app.ObstaclesNode || selectedNodes.Parent == app.TargetsNode || selectedNodes.Parent == app.BaseNode
                select_child(app,selectedNodes.NodeData.child);
    
                app.TabGroup.SelectedTab = app.PropertiesTab;
                % msgbox(string(selectedNodes.NodeData.x))
                if selectedNodes.Parent == app.TargetsNode || selectedNodes.Parent == app.BaseNode
                    app.xcoordinateEditField.Value = selectedNodes.NodeData.x;
                    app.ycoordinateEditField.Value = selectedNodes.NodeData.y;
                    app.lengthEditField.Value = 1;
                    app.angledegEditField.Value = selectedNodes.NodeData.deg;
                    app.lengthEditFieldLabel.Text = "length";
                    app.lengthEditField.Enable="off";
                    app.lengthEditFieldLabel.Enable="off";
                    enable_properties(app,"target")
                elseif selectedNodes.Parent == app.ObstaclesNode
                    app.xcoordinateEditField.Value = selectedNodes.NodeData.x;
                    app.ycoordinateEditField.Value = selectedNodes.NodeData.y;
                    app.lengthEditField.Value = selectedNodes.NodeData.Radius;
                    app.lengthEditFieldLabel.Text = "radius";
                    app.angledegEditField.Enable ="off";
                    app.angledegEditFieldLabel.Enable ="off";
                    enable_properties(app,"obstacle")
                end
            else 
                %  deselect 
            end
        end
        
        
        function create_polyshape(app,x,y,L,deg,target)
            xLimits = app.UIAxes.XLim;
                
            yLimits = app.UIAxes.YLim;
            
            % Set 'NextPlot' to 'add' to allow adding shapes without clearing
            % app.UIAxes.NextPlot = 'add';
            
            % Draw your shapes or add data here
            % For example, draw a rectangle:
            
            
            if ~target
                radius  = L;
                ps = draw_obstacle(app,x,y,radius);
                node = add_obstacle_node(app,ps,x,y,radius);
                
            elseif target
                ps = draw_target(app,x,y,deg);
                node = add_target_node(app,ps,x,y,L,deg);
            end
            ps.UserData = node;
            ps.FaceAlpha = 0.1;
            % ps.HitTest = "off";
            ps.ButtonDownFcn = @(src,event)ps_mouse_click(app,src);
            ps_mouse_click(app,ps);
            
            % Store the current axis limits
            % Restore the original axis limits
            app.UIAxes.XLim = xLimits;
            app.UIAxes.YLim = yLimits;
            
            % Set 'NextPlot' back to 'replace' to prevent further automatic additions
            % app.UIAxes.NextPlot = 'replace';
            app.Tree.expand;

        end


        function deselect_children(app)
            children = app.UIAxes.Children;
            for i = 1:length(children)
               
                    children(i).Selected = "off";
                
            end
        end
        

        function enable_properties(app,class)
            if class=="target"
                app.angledegEditField.Enable="on";
                app.angledegEditFieldLabel.Enable="on";
            elseif class=="obstacle"
                app.lengthEditFieldLabel.Enable="on";
                app.lengthEditField.Enable="on";
            end
            app.xcoordinateEditField.Enable = "on";
            app.ycoordinateEditField.Enable = "on";
            app.xcoordinateEditFieldLabel.Enable = "on";
            app.ycoordinateEditFieldLabel.Enable= "on";
            
        end


        function disable_properties(app)
            app.xcoordinateEditFieldLabel.Enable = "off";
            app.ycoordinateEditFieldLabel.Enable= "off";
            app.lengthEditFieldLabel.Enable="off";
            app.angledegEditFieldLabel.Enable="off";
            app.xcoordinateEditField.Value = 0;
            app.ycoordinateEditField.Value = 0;
            app.lengthEditField.Value=0;
            app.angledegEditField.Value=0;
            app.xcoordinateEditField.Enable = "off";
            app.ycoordinateEditField.Enable = "off";
            app.lengthEditField.Enable="off";
            app.angledegEditField.Enable="off";
        end
        

        function add_home_base_plot_and_node(app,x,y,deg)
            ps = draw_base(app,x,y,deg);
            
            node = add_base_node(app,ps,x,y,deg);
            ps.UserData = node;
            ps.ButtonDownFcn = @(src,event)ps_mouse_click(app,src);
            app.Tree.expand;
            handle_hittest(app)
        end
        

        function deselect_toggles(app,event)
            children = app.Toolbar.Children;
            child = event.Source;
            for i = 1:length(children)
                if child ~= children(i)
                    try
                    children(i).State = "off";
                    catch
                    end
                end
            end
            
        end


        function zoomOutAroundPoint(app, x_center, y_center)
            % Get the current axis limits
            currentXLimits = app.UIAxes.XLim;
            currentYLimits = app.UIAxes.YLim;
        
            % Define a zoom-out factor (e.g., 1.2 for 20% zoom out)
            zoomFactor = 0.9;
        
            % Calculate the new axis limits for zooming out while keeping the center point fixed
            newXLimits = (currentXLimits - x_center) / zoomFactor + x_center;
            newYLimits = (currentYLimits - y_center) / zoomFactor + y_center;
        
            % Update the axis limits
            app.UIAxes.XLim = newXLimits;
            app.UIAxes.YLim = newYLimits;
        end


        function turn_children_hittest(app,value)
            children = app.UIAxes.Children;
            for i = 1:length(children)
                children(i).HitTest = value;
            end
        end


        function change_pointer_to(app,tool)
            if tool=="target" || tool == "eraser" || tool == "zoomOut"
                            app.UIFigure.Pointer = "custom";
            if tool == "zoomOut"
                matrix = [  nan nan nan nan 1 1 1 1 1 nan nan nan nan nan nan nan;
                            nan nan 1 1 1 nan nan nan 1 1 1 nan nan nan nan nan;
                            nan 1 1 nan nan nan nan nan nan nan 1 1 nan nan nan nan;
                            nan 1 nan nan nan nan nan nan nan nan nan 1 1 nan nan nan;
                            1 1 nan nan nan nan nan nan nan nan nan nan 1 nan nan nan;
                            1 nan nan nan nan nan nan nan nan nan nan nan 1 nan nan nan;
                            1 nan nan nan 1 1 1 1 1 nan nan nan 1 nan nan nan;
                            1 nan nan nan nan nan nan nan nan nan nan nan 1 nan nan nan;
                            1 1 nan nan nan nan nan nan nan nan nan nan 1 nan nan nan;
                            nan 1 nan nan nan nan nan nan nan nan nan 1 1 nan nan nan;
                            nan 1 1 nan nan nan nan nan nan nan 1 1 nan nan nan nan;
                            nan nan 1 1 nan nan nan nan nan 1 1 1 nan nan nan nan;
                            nan nan nan 1 1 1 1 1 1 1 nan nan 1 nan nan nan;
                            nan nan nan nan nan nan nan nan nan nan nan nan nan 1 nan nan;
                            nan nan nan nan nan nan nan nan nan nan nan nan nan nan 1 nan;
                            nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan 1;
                         ];
                app.UIFigure.PointerShapeHotSpot = [7 7];
            elseif tool == "eraser"
                matrix = [   nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan;
                             nan nan nan nan nan nan nan nan 1 1 1 1 1 nan nan nan;
                             nan nan nan nan nan nan nan 1 1 nan nan nan 1 1 nan nan;
                             nan nan nan nan nan nan 1 1 nan nan nan nan nan 1 1 nan;
                             nan nan nan nan nan 1 1 nan nan nan nan nan nan nan 1 1;
                             nan nan nan nan 1 1 nan nan nan nan nan nan nan nan 1 1;
                             nan nan nan 1 1 1 nan nan nan nan nan nan nan nan nan 1;
                             nan nan 1 1 nan 1 1 nan nan nan nan nan nan nan 1 1;
                             nan 1 1 nan nan nan 1 1 nan nan nan nan nan 1 1 nan;
                             1 1 nan nan nan nan nan 1 1 nan nan nan 1 1 nan nan;
                             1 nan nan nan nan nan nan nan 1 1 nan 1 1 nan nan nan;
                             1 nan nan nan nan nan nan nan nan 1 1 1 nan nan nan nan;
                             1 1 nan nan nan nan nan nan nan 1 1 nan nan nan nan nan;
                             nan 1 1 nan nan nan nan nan 1 1 nan nan nan nan nan nan;
                             nan nan 1 1 1 1 1 1 1 1 1 1 1 1 1 1;
                             nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan;
                         ];
                app.UIFigure.PointerShapeHotSpot = [16 1];
            elseif tool == "target"
                % Create a 16x16 matrix with NaN values
                matrix = [ nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan
                         nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan
                         nan nan nan nan nan nan nan 1 1 nan nan nan nan nan nan nan
                         nan nan nan nan nan nan nan 1 1 nan nan nan nan nan nan nan
                         nan nan nan nan nan nan nan 1 1 nan nan nan nan nan nan nan
                         nan nan nan nan nan nan 1 1 1 1 nan nan nan nan nan nan
                         nan 1 1 1 1 1 1 nan nan 1 1 1 1 1 1 nan
                         nan nan 1 1 1 nan nan nan nan nan nan 1 1 1 nan nan
                         nan nan nan 1 1 1 nan nan nan nan 1 1 1 nan nan nan
                         nan nan nan nan nan 1 nan nan nan nan 1 nan nan nan nan nan
                         nan nan nan nan nan 1 nan nan nan nan 1 nan nan nan nan nan
                         nan nan nan nan 1 1 1 1 1 1 1 1 nan nan nan nan
                         nan nan nan nan 1 1 1 nan nan 1 1 1 nan nan nan nan
                         nan nan nan nan 1 1 nan nan nan nan 1 1 nan nan nan nan
                         nan nan nan 1 nan nan nan nan nan nan nan nan 1 nan nan nan
                         nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan nan
                        ];
                app.UIFigure.PointerShapeHotSpot = [8 8];
            end
            app.UIFigure.PointerShapeCData = matrix;
            else 
                app.UIFigure.Pointer = tool;
            end
        end

        %% Variable Creation

        function create_home_base_variable(app)
            home_base = app.BaseNode.Children(1);
            app.op.home_base = [home_base.NodeData.x home_base.NodeData.y home_base.NodeData.deg];
        end
        function create_targets_variable(app)
            nodes = app.TargetsNode.Children;
            t_v =  zeros(length(nodes),3);
            for i = 1:length(nodes)
                t_v(i,1)= nodes(i).NodeData.x;
                t_v(i,2)= nodes(i).NodeData.y;
                t_v(i,3)= nodes(i).NodeData.deg;
            end
            app.op.targets = t_v;
        end
        function create_obstacles_variable(app)
            nodes = app.ObstaclesNode.Children;
            o_v =  zeros(length(nodes),3);
            for i = 1:length(nodes)
                o_v(i,1)= nodes(i).NodeData.x;
                o_v(i,2)= nodes(i).NodeData.y;
                o_v(i,3)= nodes(i).NodeData.Radius;
            end
            app.op.obstacles = o_v ;     
        end
        function create_parameters_variables(app)
            app.op.n_nodes = app.linksEditField.Value;
            app.op.angle_domain = [app.minangleEditField.Value, app.maxangleEditField.Value];
            app.op.length_domain = [app.minlengthEditField.Value , app.maxlengthEditField.Value];

            app.op.first_angle.is_fixed = app.fixedfirstangleCheckBox.Value;
            app.op.first_angle.angle = app.firstangleEditField.Value;
        end

        %%  

        function collectOP(app)
            create_home_base_variable(app);
            create_targets_variable(app);
            create_obstacles_variable(app);
            create_parameters_variables(app);

        end


        function sendOP(app)
            loadOP(app.op, 0);
        end


        function draw_segments(app)
            try
                points = retrieveOrientationSegmentEndPoints(false);
                loadOP(app.op, points);

                % one target => points = [ x ; y]
                % two targets => points = [x y ; x y x y]
                if length(app.TargetsNode.Children)==1
    
                        child = app.TargetsNode.Children(1);
                        plot(app.UIAxes,[points(1) child.NodeData.x],[points(2) child.NodeData.y], 'Color','blue','LineStyle','--')
                        plot(app.UIAxes, points(1),points(2),'ko','markerfacecolor','k')
                    
                else
                    for i = 1:length(points)
                        point = points(i,:);
                        child = app.TargetsNode.Children(i);
                        plot(app.UIAxes,[point(1) child.NodeData.x],[point(2) child.NodeData.y], 'Color','blue','LineStyle','--')
                        plot(app.UIAxes, point(1),point(2),'ko','markerfacecolor','k')
                    end
                end
                
            catch ME
                % uialert(app.UIFigure,getReport(ME),'Error')
                uialert(app.UIFigure,ME.message,'Error')
            end
        end
        
        function remove_segment_lines(app)
            children = app.UIAxes.Children;
            for i = 1:length(children)
                if isempty(children(i).UserData)
                    delete(children(i))

                end
            end
        end

        
        function value = doesOverlap(app)
            children = app.UIAxes.Children;
            children_shape = polyshape.empty(numel(children),0);
            for i = 1 : length(children)
                children_shape(i) = children(i).Shape;
            end
            
            TF = overlaps(children_shape);
            s = sum(TF,'all');
            if s > length(children)
                uialert(app.UIFigure,"Intersections found","Warning","Icon","warning")
                value = true;
                return
            end
            value = false;
        end
        
        function  resetOptions(app)
            disable_properties(app)
            app.linksEditField.Value = 0;
            app.minangleEditField.Value = 0;
            app.maxangleEditField.Value = 0;
            app.minlengthEditField.Value=0;
            app.maxlengthEditField.Value = 0;
            app.firstangleEditField.Value = 0;
            app.fixedfirstangleCheckBox.Value=false;
        end
        
        function value = importFile(app)
            [fileName, pathName] = uigetfile('*.mat', 'Select a .mat file');
    
            if ischar(fileName) && ischar(pathName)
                % File selected successfully
                fullPath = fullfile(pathName, fileName);
                
                % Now, you can load the .mat file or process it as needed
                % For example, you can use the "load" function to load the data:
                app.loadedData = load(fullPath);
                value = true;
                return
                % Perform further actions with the loaded data
                
            end
            value = false;
        end
        
        function value = confirmAction(app,title)
            fig = app.UIFigure;
            msg = "Loss of previous changes can occur.";
            selection = uiconfirm(fig,msg,title, ...
                "Options",["OK","Cancel"], ...
                "DefaultOption",2,"CancelOption",2);
            value = selection;

        end
        
        function newConfiguration(app)
            resetOptions(app)
            remove_segment_lines(app)
            disable_properties(app)
            
            children = app.UIAxes.Children;
            for i = 1:length(children)
                delete(children(i).UserData)
                delete(children(i))
                
            end
            add_home_base_plot_and_node(app,0,0,0);
        end
        
        function parseDataIntoConfiguration(app)
            app.op = app.loadedData.saveVar;

            % Delete default base
            delete(app.BaseNode.Children(1))
            delete(app.UIAxes.Children(1))

            % Add a base
            base = app.op.home_base;
            add_home_base_plot_and_node(app,base(1),base(2),base(3))

            % Add obstacles
            obstacles = app.op.obstacles;
            for i = 1:length(obstacles(:,1))
                create_polyshape(app,obstacles(i,1),obstacles(i,2),obstacles(i,3),0,false);
            end

            % Add targets 
            targets = app.op.targets;
            for i = 1 : length(targets(:,1))
                create_polyshape(app,targets(i,1),targets(i,2),1,targets(i,3),true);
            end
            
            % Add n_nodes
            n_nodes = app.op.n_nodes;
            app.linksEditField.Value = n_nodes;

            % Add angle domain
            min_angle = app.op.angle_domain(1);
            max_angle = app.op.angle_domain(2);

            app.minangleEditField.Value = min_angle;
            app.maxangleEditField.Value = max_angle;

            % Add length domain;
            min_length = app.op.length_domain(1);
            max_length = app.op.length_domain(2);

            app.minlengthEditField.Value = min_length;
            app.maxlengthEditField.Value = max_length;

            % Add first angle
            angle = app.op.first_angle.angle;
            is_fixed = app.op.first_angle.is_fixed;

            app.firstangleEditField.Value = angle;
            app.fixedfirstangleCheckBox.Value = is_fixed;

            [~] = init_segments(app);
        end
        
        function loadDefaultOptions(app)
            % robot parameters
            app.linksEditField.Value = 20;
            app.minangleEditField.Value = -45;
            app.maxangleEditField.Value = 45;
            app.minlengthEditField.Value= 10;
            app.maxlengthEditField.Value = 40;
            app.firstangleEditField.Value = 0;
            app.fixedfirstangleCheckBox.Value = true;
            
            % optimization algorithm
            app.algorithmDropDown.Value = "Genetic Algorithm";
            app.generationsEditField.Value = 100;
            app.individualsEditField.Value = 500;
            app.penaltymethodDropDown.Value = "static";
            app.obstacleavoidanceCheckBox.Value = true;

            % ga
            app.slectionmethodDropDown.Value = "tournament";
            app.crossovermethodDropDown.Value = "blxa";
            app.crossoverprobabilitySpinner.Value = 0.9;
            app.mutationmethodDropDown.Value = "random";
            app.mutationprobabilitySpinner.Value = 0.4;
            app.survivalmethodDropDown.Value = "elitist_full";
            app.survivalalphaEditField.Value = 40;
            app.survivalalphaEditField.Visible = "off";
            app.survivalalphaEditFieldLabel.Visible = "off";

            % bbbc
            app.crunchmethodDropDown.Value = "fittest";

            % rank partitioning
            app.methodDropDown.Value = "penalty";
            app.stepIKEditField.Value = 0.5;
            app.steplengthEditField.Value = 5;
        end
        
        function Apply(app)
            selectedNode = app.Tree.SelectedNodes;
            child = selectedNode.NodeData.child;
            delete(child);
            x = app.xcoordinateEditField.Value;
            y = app.ycoordinateEditField.Value;
            selectedNode.NodeData.x=x;
            selectedNode.NodeData.y=y;
            if selectedNode.Parent == app.TargetsNode
                % L = app.lengthEditField.Value;
                deg = app.angledegEditField.Value ;
                selectedNode.NodeData.L = 2;
                selectedNode.NodeData.deg = deg;
                selectedNode.Text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", deg: " + string(round(deg,2));
                ps = draw_target(app,x,y,deg);
                
            elseif selectedNode.Parent == app.ObstaclesNode
                radius = app.lengthEditField.Value;
                selectedNode.NodeData.Radius = radius;
                selectedNode.Text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", r: " + string(round(radius,2));
                ps = draw_obstacle(app,x,y,radius);
            elseif selectedNode.Parent == app.BaseNode
                deg = app.angledegEditField.Value ;
                selectedNode.NodeData.L = 1;
                selectedNode.NodeData.deg = deg;
                selectedNode.Text = "x: " + string(round(x,2)) + ", y: " + string(round(y,2)) + ", deg: " + string(round(deg,2));
                ps = draw_base(app,x,y,deg);
            end
            
            ps.UserData = selectedNode;
            ps.FaceAlpha = 0.1;
            selectedNode.NodeData.child = ps;
            ps.ButtonDownFcn = @(src,event)ps_mouse_click(app,src);
            ps_mouse_click(app,ps);


        end
        
        function result = init_segments(app)
            removePreviousMinMax(app);
            remove_segment_lines(app)
            overlaps = doesOverlap(app);
            if ~overlaps
                collectOP(app);
                sendOP(app);
                draw_segments(app);
                handle_hittest(app)                
            end
            drawMinMax(app);
            result = overlaps;

            % draws min max lengths
            drawMinMaxCoordinates(app)
        end
        
        function drawRobotConfigurations(app,configs)
            % from main script drawProblem2D
            n_targets = size(app.op.targets,1);
            if isempty(configs) == false
            for i=1:1:n_targets
                conf = configs(:,:,i);
                xy = solveForwardKinematics2D(conf,app.op.home_base,false); % solve forward kinematics to draw the robot configuration
                for j = 1 : 1 : size(xy,1)-1
                    plot(app.UIAxes,[xy(j,1),xy(j+1,1)],[xy(j,2),xy(j+1,2)],'-o','Color','r'); % print each node of the robot configuration
                end  
            end
            end

        end


        function handle_hittest(app)
            pointer = app.UIFigure.Pointer;
            if pointer == "arrow" || app.ZoomOut.UserData == "eraser"
                turn_children_hittest(app,"on")
            else
                turn_children_hittest(app,"off")
            end
        end
        
        function deleteSelected(app)
            selectedNodes = app.Tree.SelectedNodes;
            delete(selectedNodes.NodeData.child);
            delete(selectedNodes);
            % deselect(app)
            [~] = init_segments(app);
        end
        
        function bbbcOptionsVisibility(app,value)
            app.crunchmethodDropDown.Visible = value;
            app.crunchmethodDropDownLabel.Visible = value;
        end
        
        function gaOptionsVisibility(app,value)
            app.slectionmethodDropDown.Visible = value;
            app.slectionmethodDropDownLabel.Visible = value;
            app.crossovermethodDropDown.Visible = value;
            app.crossovermethodDropDownLabel.Visible = value;
            app.crossoverprobabilitySpinner.Visible = value;
            app.crossoverprobabilitySpinnerLabel.Visible = value;
            app.mutationmethodDropDown.Visible = value;
            app.mutationmethodDropDownLabel.Visible = value;
            app.mutationprobabilitySpinner.Visible = value;
            app.mutationprobabilitySpinnerLabel.Visible = value;
            app.survivalmethodDropDown.Visible = value;
            app.survivalmethodDropDownLabel.Visible = value;
            app.survivalalphaEditField.Visible = value;
            app.survivalalphaEditFieldLabel.Visible = value;
            updateSurvivalAlphaVisibility(app,value)
        end
        
        function sendRunItGeneric(app)
            collectRunItGeneric(app);
            loadRunItGeneric(app.algorithm,app.gas,app.bbbcs);
        end
        function collectRunItGeneric(app)
            if app.algorithmDropDown.Value == "Genetic Algorithm"
                app.algorithm = "ga";
            elseif app.algorithmDropDown.Value == "Big-Bang Big-Crunch"
                app.algorithm = "bbbc";
            end
            
            switch app.algorithm
                case "ga"
                    app.gas.generations = app.generationsEditField.Value;
                    app.gas.n_individuals = app.individualsEditField.Value;
                    app.gas.obstacle_avoidance = app.obstacleavoidanceCheckBox.Value;
                    app.gas.selection_method = app.slectionmethodDropDown.Value;    % 'tournament', 'proportionate'
                    app.gas.crossover_method = app.crossovermethodDropDown.Value;  % 'blxa'
                    app.gas.crossover_probability = app.crossoverprobabilitySpinner.Value;
                    app.gas.mutation_method = app.mutationmethodDropDown.Value;   % 'random', 'modifiedRandom'
                    app.gas.mutation_probability = app.mutationprobabilitySpinner.Value;  % -1 is dynamic 
                    app.gas.survival_method = app.survivalmethodDropDown.Value; % 'elitist_full', 'elitist_alpha'
                    app.gas.survival_alpha = app.survivalalphaEditField.Value;    %this is the percentage of elites that will stay in the new population
                    app.gas.penalty_method = app.penaltymethodDropDown.Value;	% 'static', 'deb'
                    % settings of rank partitioning algorithm
                    app.gas.ranking_method = app.methodDropDown.Value;     % 'penalty', 'separation'
                    app.gas.rankingSettings.step_ik = app.stepIKEditField.Value;       % resolution of a partition (i.e., distance in IK fitness between two consecutives paritions)
                    app.gas.rankingSettings.step_len = app.steplengthEditField.Value;
                case "bbbc"
                    app.bbbcs.MAX_GENERATIONS = app.generationsEditField.Value;
                    app.bbbcs.N = app.individualsEditField.Value;

                    app.bbbcs.crunchMethod = app.crunchmethodDropDown.Value;   % 'fittest', 'com' -- fittest: choosing the fittest one as center of mass, com: calculating the center of mass with the equation on the paper

                    app.bbbcs.obstacle_avoidance = app.obstacleavoidanceCheckBox.Value;
                    app.bbbcs.penalty_method = app.penaltymethodDropDown.Value;	% 'static', 'deb'
                    
                    % settings of rank partitioning algorithm
                    app.bbbcs.ranking_method = app.methodDropDown.Value;     % 'penalty', 'separation'
                    app.bbbcs.rankingSettings.step_ik = app.stepIKEditField.Value;       % resolution of a partition (i.e., distance in IK fitness between two consecutives paritions)
                    app.bbbcs.rankingSettings.step_len = app.steplengthEditField.Value;
            end
        end
        
        function updateSurvivalAlphaVisibility(app,val)
            if val == "on"
                value = app.survivalmethodDropDown.Value;
                if value == "elitist_alpha"
                    app.survivalalphaEditField.Visible = "on";
                    app.survivalalphaEditFieldLabel.Visible = "on";
                elseif value == "elitist_full"
                    app.survivalalphaEditField.Visible = "off";
                    app.survivalalphaEditFieldLabel.Visible = "off";
                end
            end
        end
        
        function result = getDesign(~,configurations)
            [~, ~ , NArrays] = size(configurations);
            max = 0;
            maxIndex = 0;
            maxLength = 0;
            for i = 1:NArrays
                if numel(nonzeros(configurations(:,end,i))) > max
                    max = numel(nonzeros(configurations(:,end,i)));
                    nonZMatrix = nonzeros(configurations(:,end,i));
                    maxLength = nonZMatrix(end);
                    maxIndex = i;
                elseif numel(nonzeros(configurations(:,end,i))) == max
                    nonZMatrix = nonzeros(configurations(:,end,i));
                    lastLength = nonZMatrix(end);
                    if lastLength > maxLength
                        maxIndex = i;
                        maxLength = lastLength;
                    end
                end
            end
            design = " Design [ ";
            
            for i = 1:max
                design = design + string(nonzeros(configurations(i,end,maxIndex))) + "    ";
            end
            design = design + " ]";
            result = design;
        end
        
        function drawMinMax(app)
            
            %  Get the coordinates of the points for drawing lines
            [x1,y1,x2,y2 ] = drawMinMaxCoordinates(app);
            darkOrange = "#bf6c00";
            % Plot the line
            plot(app.UIAxes,x1, y1, '-o',"UserData","Min Max", color=darkOrange); % Use '-o' to connect the points with a line and add markers
            plot(app.UIAxes,x2, y2, '-o',"UserData","Min Max", color=darkOrange); % Use '-o' to connect the points with a line and add markers
            
        end
        
        
        function  [x1,y1,x2,y2 ] = drawMinMaxCoordinates(app)
                        
            yLimUp = app.UIAxes.YLim(end);
            yLimDown = app.UIAxes.YLim(1);
            yLength = yLimUp - yLimDown;

            xLimRight = app.UIAxes.XLim(end);
            xLimLeft = app.UIAxes.XLim(1);
            xLength = xLimRight - xLimLeft;

            yTenPercent = yLength/10;
            xTenPercent = xLength/10;
            
            
            min = app.minlengthEditField.Value;
            max = app.maxlengthEditField.Value;

            % Define the coordinates of the line
            x1 = [xLimLeft+xTenPercent/2, xLimLeft+xTenPercent/2+max];
            y1 = [yLimUp-yTenPercent/2, yLimUp-yTenPercent/2];
            x2 = [xLimLeft+xTenPercent/2, xLimLeft+xTenPercent/2+min];
            y2 = [yLimUp-7*yTenPercent/10, yLimUp-7*yTenPercent/10];
            
        end
        
        function removePreviousMinMax(app)
            children = app.UIAxes.Children;
            for i = 1:length(children)
                try
                    if children(i).UserData == "Min Max"
                        delete(children(i));
                    end
                catch
                end
            end
        end
    end
    
    methods (Access = public)
        
        function sendOutputFromScript2GUI(app,message,configurations)
            result = getDesign(app,configurations);
            message = string(message )+ string(result);
            node = uitreenode(app.Tree2,"Text",string(message));
            node.NodeData = configurations;
            app.Tree2.scroll("bottom")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                    % hold(app.UIAxes,'on')
        app.nChar = newline; % Description
        app.tabChar = char(9);
        axis(app.UIAxes,'equal');
        hold(app.UIAxes,"on");
        add_home_base_plot_and_node(app,0,0,0);
        disable_properties(app);
        axtoolbar(app.UIAxes,"default","Visible","off");
        app.ZoomOut.UserData = "arrow";
        % pan(ax,'off')
        % zoom(ax,'on','factor',0.1);
        % define the x- and y-data for the original line we would like to rotate
        app.UIAxes.XLim = app.XLim;
        app.UIAxes.YLim = app.YLim;
        loadDefaultOptions(app)

        % Draw Min Max Lengths
        drawMinMax(app);
        
        end

        % Button down function: UIAxes
        function UIAxesButtonDown3(app, event)
            currentPoint = app.UIAxes.CurrentPoint;
             x = currentPoint(1,1);
             y = currentPoint(1,2);
             
             if app.ZoomOut.State == "on"
                 zoomOutAroundPoint(app,x,y);
             elseif app.Obstacle.State == "on"
                 create_polyshape(app,x,y,1,0,false);
                 [~] = init_segments(app);
             elseif app.Target.State == "on"
                 create_polyshape(app,x,y,1,0,true);
                 [~] = init_segments(app);
             elseif app.Pointer.State=="on"
                 children = app.UIAxes.Children;
                for i = 1:length(children)
                    if children(i).Selected == "on"
                        ps_mouse_click(app,children(i));
     
                    end
                end
             end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if selectedNodes ~= app.ObstaclesNode && selectedNodes ~= app.TargetsNode && selectedNodes~= app.BaseNode
            node_click(app);
            else
                disable_properties(app);
                deselect_children(app);
            end
        end

        % Value changed function: angledegEditField, lengthEditField, 
        % ...and 2 other components
        function xcoordinateEditFieldValueChanged(app, event)
            Apply(app);
            [~] = init_segments(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

        end

        % On callback: Pointer
        function PointerOn(app, event)
            % Pointer
            app.ZoomOut.UserData = "arrow";
            
            app.Target.State="off";
            app.Obstacle.State = "off";
            app.Eraser.State = "off";
            
            if app.ZoomOut.State == "off"
                turn_children_hittest(app,"on")
                app.UIFigure.Pointer = 'arrow';
            end
        end

        % On callback: Obstacle
        function ObstacleOn(app, event)
            % Obstacle
            app.Pointer.State="off";
            app.Target.State = "off";
            app.Eraser.State = "off";
            app.ZoomOut.UserData = "circle";
            % to be able to draw on them
            
            if app.ZoomOut.State == "off"
                app.UIFigure.Pointer = "circle";
                turn_children_hittest(app,"off")
            end
        end

        % Off callback: Pointer
        function PointerOff(app, event)
            
        end

        % Callback function: Eraser, Obstacle, Pointer, Target
        function PointerClicked(app, event)
            if event.Source.State == "off"
                event.Source.State = "on";
            end
            
        end

        % On callback: Target
        function TargetOn2(app, event)
            app.Pointer.State='off';
            app.Obstacle.State = 'off';
            app.Eraser.State = 'off';
            app.ZoomOut.UserData = 'target';
            % To be able to draw on them
            if app.ZoomOut.State == "off"
                turn_children_hittest(app,"off")
                change_pointer_to(app,'target');
            end
        end

        % On callback: Eraser
        function EraserOn(app, event)
            disable_properties(app);
            app.Pointer.State='off';
            app.Obstacle.State = 'off';
            app.Target.State = 'off';
            app.ZoomOut.UserData = 'eraser';
            if app.ZoomOut.State == "off"
                change_pointer_to(app,'eraser');
                turn_children_hittest(app,"on")
            end
        end

        % On callback: ZoomIn
        function ZoomInOn(app, event)
            app.Pan.State = 'off';
            app.ZoomOut.State = 'off';
            pan(app.UIAxes,'off');
        end

        % On callback: ZoomOut
        function ZoomOutOn(app, event)
            turn_children_hittest(app,'off');
            
            app.ZoomIn.State = 'off';
            app.Pan.State = 'off';
            zoom(app.UIAxes,'off');
            pan(app.UIAxes,'off');
            change_pointer_to(app,'zoomOut');
            % zoomOut(app);


        end

        % Callback function: Pan
        function PanClicked(app, event)
            pan(app.UIAxes,'toggle');
            if (app.Pan.State=="off")
                % change_pointer_to(app,app.ZoomOut.UserData);
                switch(app.ZoomOut.UserData)
                    case 'arrow'
                        
                        PointerOn(app,event)
                    case 'circle'
                        ObstacleOn(app,event)
                    case 'target'
                        TargetOn2(app,event)
                    case 'eraser'
                        EraserOn(app,event)
                end
                
            end
        end

        % Callback function: ZoomIn
        function ZoomInClicked(app, event)
            zoom(app.UIAxes,'toggle');
            if app.ZoomIn.State == "off"
                % change_pointer_to(app,app.ZoomOut.UserData);
            switch(app.ZoomOut.UserData)
                    case 'arrow'
                        
                        PointerOn(app,event)
                    case 'circle'
                        ObstacleOn(app,event)
                    case 'target'
                        TargetOn2(app,event)
                    case 'eraser'
                        EraserOn(app,event)
             end

            end
        end

        % On callback: Pan
        function PanOn2(app, event)
            app.ZoomIn.State = 'off';
            app.ZoomOut.State = 'off';
            zoom(app.UIAxes,'off');
        end

        % Callback function: RestoreView
        function RestoreViewClicked(app, event)
            app.UIAxes.XLim =  app.XLim;
            app.UIAxes.YLim =  app.YLim;
        end

        % Off callback: ZoomOut
        function ZoomOutOff(app, event)
            
        end

        % Callback function: ZoomOut
        function ZoomOutClicked(app, event)
            if app.ZoomOut.State == "off"
                turn_children_hittest(app,"on");
            
                % change_pointer_to(app,app.ZoomOut.UserData);
            switch(app.ZoomOut.UserData)
                    case 'arrow'
                        
                        PointerOn(app,event)
                    case 'circle'
                        ObstacleOn(app,event)
                    case 'target'
                        TargetOn2(app,event)
                    case 'eraser'
                        EraserOn(app,event)
             end

            end            
            

        end

        % Callback function: Run
        function RunClicked(app, event)
            try
                result = init_segments(app);
                if ~result
                    app.stopRunItGeneric = false;
                    sendRunItGeneric(app);
                    [~,configurations] = runIt_generic(app);
                    drawRobotConfigurations(app,configurations);
                end
            catch er
                uialert(app.UIFigure, er.message, 'Error');

            end

        end

        % Callback function: PushTool4
        function PushTool4Clicked(app, event)
            remove_segment_lines(app)

        end

        % Value changed function: firstangleEditField, 
        % ...and 6 other components
        function nodesEditFieldValueChanged2(app, event)
            [~] = init_segments(app);
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
            
            saveVar = app.op;
            [file, path] = uiputfile('*.mat', 'MATLAB Data File');
            if isequal(file, 0)
                % User canceled the operation
            else
                % Combine the selected path and file name
                fullFilePath = fullfile(path, file);
                
                % Open the file for writing
                
                % Check if the file was successfully opened
                
                    % Write "hello" to the file
                    save(fullFilePath, 'saveVar');
                    
                    % Close the file
                    
                    % Display a confirmation message
                
            end

        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            selection = confirmAction(app,'New Document');
            if selection == "OK"
                newConfiguration(app)
                loadDefaultOptions(app)
                RestoreViewClicked(app, event)
            end
        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            value = importFile(app);
            if value == true
                selection = confirmAction(app,'Open Document');
                if selection == "OK"
                    newConfiguration(app)
                    parseDataIntoConfiguration(app)
                    app.Pointer.State = "on";
                    % otherwise they'd not be clickable at first
                    handle_hittest(app)
                end
            end
                            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = confirmAction(app,'Exit');
            if selection == "OK"
                delete(app)
            end


        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            try
                % Set the zoom factor (adjust as needed)
                zoomFactor = 0.9;
            
                % Get the current cursor coordinates
                currentPoint = app.UIAxes.CurrentPoint;
                x = currentPoint(1,1);
                y = currentPoint(1,2);
                
                % Get the current axis limits
                currentXLimits = app.UIAxes.XLim;
                currentYLimits = app.UIAxes.YLim;
                
                % Check if cursor coordinates are inside the axes limits
                insideAxes = x >= currentXLimits(1) && x <= currentXLimits(2) && ...
                              y >= currentYLimits(1) && y <= currentYLimits(2);
                
                if insideAxes
                    removePreviousMinMax(app);
                    

                    if event.VerticalScrollCount > 0
                        % Calculate the new axis limits for zooming out while keeping the center point fixed
                        newXLimits = (currentXLimits - x) / zoomFactor + x;
                        newYLimits = (currentYLimits - y) / zoomFactor + y;
                    else
                        % Calculate the new axis limits for zooming in while keeping the center point fixed
                        newXLimits = (currentXLimits - x) * zoomFactor + x;
                        newYLimits = (currentYLimits - y) * zoomFactor + y;
                    end
            
                    % Update the axis limits
                    app.UIAxes.XLim = newXLimits;
                    app.UIAxes.YLim = newYLimits;
                    drawMinMax(app);
                end
            catch er
                uialert(app.UIFigure, er.message, 'Error');
            end            
        end

        % Value changed function: algorithmDropDown
        function algorithmDropDownValueChanged(app, event)
            value = app.algorithmDropDown.Value;
            if value=="Genetic Algorithm"
                bbbcOptionsVisibility(app,"off");
                gaOptionsVisibility(app,"on")
            elseif value == "Big-Bang Big-Crunch"
                bbbcOptionsVisibility(app,"on");
                gaOptionsVisibility(app,"off")
            end
        end

        % Callback function: PushTool5
        function PushTool5Clicked(app, event)
            app.stopRunItGeneric = true;

        end

        % Value changed function: survivalmethodDropDown
        function survivalmethodDropDownValueChanged(app, event)
            updateSurvivalAlphaVisibility(app, "on")
        end

        % Selection changed function: Tree2
        function Tree2SelectionChanged(app, event)
            selectedNodes = app.Tree2.SelectedNodes;
            [~] = init_segments(app);
            drawRobotConfigurations(app,selectedNodes.NodeData)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'New document'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'add-document.png');
            app.PushTool.Separator = 'on';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Save document'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'disk.png');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Open document'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'folder-open.png');
            app.Pointer = uitoggletool(app.Toolbar);
            app.Pointer.Tooltip = {'Select'};
            app.Pointer.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Pointer.Icon = fullfile(pathToMLAPP, 'cursor.png');
            app.Pointer.Separator = 'on';
            app.Pointer.OffCallback = createCallbackFcn(app, @PointerOff, true);
            app.Pointer.OnCallback = createCallbackFcn(app, @PointerOn, true);
            app.Pointer.State = 'on';
            app.Target = uitoggletool(app.Toolbar);
            app.Target.Tooltip = {'Target'};
            app.Target.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Target.Icon = fullfile(pathToMLAPP, 'star.png');
            app.Target.OnCallback = createCallbackFcn(app, @TargetOn2, true);
            app.Obstacle = uitoggletool(app.Toolbar);
            app.Obstacle.Tooltip = {'Obstacle'};
            app.Obstacle.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Obstacle.Icon = fullfile(pathToMLAPP, 'circle.png');
            app.Obstacle.OnCallback = createCallbackFcn(app, @ObstacleOn, true);
            app.Eraser = uitoggletool(app.Toolbar);
            app.Eraser.Tooltip = {'Eraser'};
            app.Eraser.ClickedCallback = createCallbackFcn(app, @PointerClicked, true);
            app.Eraser.Icon = fullfile(pathToMLAPP, 'eraser.png');
            app.Eraser.OnCallback = createCallbackFcn(app, @EraserOn, true);
            app.Run = uipushtool(app.Toolbar);
            app.Run.Tooltip = {'Run'};
            app.Run.ClickedCallback = createCallbackFcn(app, @RunClicked, true);
            app.Run.Icon = fullfile(pathToMLAPP, 'play.png');
            app.Run.Separator = 'on';
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.ClickedCallback = createCallbackFcn(app, @PushTool5Clicked, true);
            app.PushTool5.Icon = fullfile(pathToMLAPP, 'stop.png');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Clean'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, 'broom.png');
            app.Pan = uitoggletool(app.Toolbar);
            app.Pan.Tooltip = {'Pan'};
            app.Pan.ClickedCallback = createCallbackFcn(app, @PanClicked, true);
            app.Pan.Icon = fullfile(pathToMLAPP, 'hand-paper.png');
            app.Pan.Separator = 'on';
            app.Pan.OnCallback = createCallbackFcn(app, @PanOn2, true);
            app.ZoomIn = uitoggletool(app.Toolbar);
            app.ZoomIn.Tooltip = {'Zoom in'};
            app.ZoomIn.ClickedCallback = createCallbackFcn(app, @ZoomInClicked, true);
            app.ZoomIn.Icon = fullfile(pathToMLAPP, 'zoom-in.png');
            app.ZoomIn.OnCallback = createCallbackFcn(app, @ZoomInOn, true);
            app.ZoomOut = uitoggletool(app.Toolbar);
            app.ZoomOut.Tooltip = {'Zoom out'};
            app.ZoomOut.ClickedCallback = createCallbackFcn(app, @ZoomOutClicked, true);
            app.ZoomOut.Icon = fullfile(pathToMLAPP, 'zoom-out.png');
            app.ZoomOut.OffCallback = createCallbackFcn(app, @ZoomOutOff, true);
            app.ZoomOut.OnCallback = createCallbackFcn(app, @ZoomOutOn, true);
            app.RestoreView = uipushtool(app.Toolbar);
            app.RestoreView.Tooltip = {'Restore view'};
            app.RestoreView.ClickedCallback = createCallbackFcn(app, @RestoreViewClicked, true);
            app.RestoreView.Icon = fullfile(pathToMLAPP, 'house-chimney.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown3, true);
            app.OptionsLabel = uilabel(app.GridLayout);
            app.OptionsLabel.Text = 'Options';
            app.ObjectsLabel = uilabel(app.GridLayout);
            app.ObjectsLabel.Text = 'Objects';
            app.Tree = uitree(app.GridLayout);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.BaseNode = uitreenode(app.Tree);
            app.BaseNode.Icon = fullfile(pathToMLAPP, 'house-chimney.png');
            app.BaseNode.Text = 'Base';
            app.TargetsNode = uitreenode(app.Tree);
            app.TargetsNode.Icon = fullfile(pathToMLAPP, 'star.png');
            app.TargetsNode.Text = 'Targets';
            app.ObstaclesNode = uitreenode(app.Tree);
            app.ObstaclesNode.Icon = fullfile(pathToMLAPP, 'circle.png');
            app.ObstaclesNode.Text = 'Obstacles';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PropertiesTab = uitab(app.TabGroup);
            app.PropertiesTab.Title = 'Properties';
            app.GridLayout3 = uigridlayout(app.PropertiesTab);
            app.xcoordinateEditFieldLabel = uilabel(app.GridLayout3);
            app.xcoordinateEditFieldLabel.Text = 'x-coordinate ';
            app.xcoordinateEditField = uieditfield(app.GridLayout3, 'numeric');
            app.xcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.ycoordinateEditFieldLabel = uilabel(app.GridLayout3);
            app.ycoordinateEditFieldLabel.Text = 'y-coordinate ';
            app.ycoordinateEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ycoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.lengthEditFieldLabel = uilabel(app.GridLayout3);
            app.lengthEditFieldLabel.Text = 'length';
            app.lengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.lengthEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.angledegEditFieldLabel = uilabel(app.GridLayout3);
            app.angledegEditFieldLabel.Text = 'angle (deg)';
            app.angledegEditField = uieditfield(app.GridLayout3, 'numeric');
            app.angledegEditField.ValueChangedFcn = createCallbackFcn(app, @xcoordinateEditFieldValueChanged, true);
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.RobotParametersTab = uitab(app.TabGroup2);
            app.RobotParametersTab.Title = 'Robot Parameters';
            app.GridLayout2 = uigridlayout(app.RobotParametersTab);
            app.fixedfirstangleCheckBox = uicheckbox(app.GridLayout2);
            app.fixedfirstangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.fixedfirstangleCheckBox.Text = 'fixed first angle';
            app.linksEditFieldLabel = uilabel(app.GridLayout2);
            app.linksEditFieldLabel.Text = 'links';
            app.linksEditField = uieditfield(app.GridLayout2, 'numeric');
            app.linksEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.minangleEditFieldLabel = uilabel(app.GridLayout2);
            app.minangleEditFieldLabel.Text = 'min angle';
            app.minangleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.minangleEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.maxangleEditFieldLabel = uilabel(app.GridLayout2);
            app.maxangleEditFieldLabel.Text = 'max angle';
            app.maxangleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.maxangleEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.minlengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.minlengthEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.minlengthEditFieldLabel = uilabel(app.GridLayout2);
            app.minlengthEditFieldLabel.Text = 'min length';
            app.maxlengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.maxlengthEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.maxlengthEditFieldLabel = uilabel(app.GridLayout2);
            app.maxlengthEditFieldLabel.Text = 'max length';
            app.firstangleEditFieldLabel = uilabel(app.GridLayout2);
            app.firstangleEditFieldLabel.Text = 'first angle';
            app.firstangleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.firstangleEditField.ValueChangedFcn = createCallbackFcn(app, @nodesEditFieldValueChanged2, true);
            app.OptimizationAlgorithmTab = uitab(app.TabGroup2);
            app.OptimizationAlgorithmTab.Title = 'Optimization Algorithm';
            app.GridLayout4 = uigridlayout(app.OptimizationAlgorithmTab);
            app.obstacleavoidanceCheckBox = uicheckbox(app.GridLayout4);
            app.obstacleavoidanceCheckBox.Text = 'obstacle avoidance';
            app.slectionmethodDropDownLabel = uilabel(app.GridLayout4);
            app.slectionmethodDropDownLabel.Text = 'slection method';
            app.slectionmethodDropDown = uidropdown(app.GridLayout4);
            app.slectionmethodDropDown.Items = {'tournament', 'proportionate'};
            app.slectionmethodDropDown.Value = 'tournament';
            app.mutationprobabilitySpinnerLabel = uilabel(app.GridLayout4);
            app.mutationprobabilitySpinnerLabel.Text = 'mutation probability';
            app.mutationprobabilitySpinner = uispinner(app.GridLayout4);
            app.mutationprobabilitySpinner.Step = 0.1;
            app.mutationprobabilitySpinner.Limits = [0 1];
            app.survivalmethodDropDownLabel = uilabel(app.GridLayout4);
            app.survivalmethodDropDownLabel.Text = 'survival method';
            app.survivalmethodDropDown = uidropdown(app.GridLayout4);
            app.survivalmethodDropDown.Items = {'elitist_full', 'elitist_alpha'};
            app.survivalmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @survivalmethodDropDownValueChanged, true);
            app.survivalmethodDropDown.Value = 'elitist_full';
            app.survivalalphaEditFieldLabel = uilabel(app.GridLayout4);
            app.survivalalphaEditFieldLabel.Text = 'survival alpha';
            app.survivalalphaEditField = uieditfield(app.GridLayout4, 'numeric');
            app.algorithmDropDownLabel = uilabel(app.GridLayout4);
            app.algorithmDropDownLabel.Text = 'algorithm';
            app.algorithmDropDown = uidropdown(app.GridLayout4);
            app.algorithmDropDown.Items = {'Genetic Algorithm', 'Big-Bang Big-Crunch'};
            app.algorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @algorithmDropDownValueChanged, true);
            app.algorithmDropDown.Value = 'Genetic Algorithm';
            app.generationsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.generationsEditFieldLabel = uilabel(app.GridLayout4);
            app.generationsEditFieldLabel.Text = 'generations';
            app.individualsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.individualsEditFieldLabel = uilabel(app.GridLayout4);
            app.individualsEditFieldLabel.Text = 'individuals';
            app.penaltymethodDropDown = uidropdown(app.GridLayout4);
            app.penaltymethodDropDown.Items = {'static', 'deb'};
            app.penaltymethodDropDown.Value = 'static';
            app.penaltymethodDropDownLabel = uilabel(app.GridLayout4);
            app.penaltymethodDropDownLabel.Text = 'penalty method';
            app.crunchmethodDropDown = uidropdown(app.GridLayout4);
            app.crunchmethodDropDown.Items = {'fittest', 'com'};
            app.crunchmethodDropDown.Visible = 'off';
            app.crunchmethodDropDown.Value = 'fittest';
            app.crunchmethodDropDownLabel = uilabel(app.GridLayout4);
            app.crunchmethodDropDownLabel.Visible = 'off';
            app.crunchmethodDropDownLabel.Text = 'crunch method';
            app.crossovermethodDropDown = uidropdown(app.GridLayout4);
            app.crossovermethodDropDown.Items = {'blxa', 'sbx'};
            app.crossovermethodDropDown.Value = 'blxa';
            app.crossovermethodDropDownLabel = uilabel(app.GridLayout4);
            app.crossovermethodDropDownLabel.Text = 'crossover method';
            app.crossoverprobabilitySpinner = uispinner(app.GridLayout4);
            app.crossoverprobabilitySpinner.Step = 0.1;
            app.crossoverprobabilitySpinner.Limits = [0 1];
            app.crossoverprobabilitySpinnerLabel = uilabel(app.GridLayout4);
            app.crossoverprobabilitySpinnerLabel.Text = 'crossover probability';
            app.mutationmethodDropDown = uidropdown(app.GridLayout4);
            app.mutationmethodDropDown.Items = {'random', 'modified random'};
            app.mutationmethodDropDown.Value = 'random';
            app.mutationmethodDropDownLabel = uilabel(app.GridLayout4);
            app.mutationmethodDropDownLabel.Text = 'mutation method';
            app.RankAlgorithmTab = uitab(app.TabGroup2);
            app.RankAlgorithmTab.Title = 'Rank Algorithm';
            app.GridLayout5 = uigridlayout(app.RankAlgorithmTab);
            app.methodDropDownLabel = uilabel(app.GridLayout5);
            app.methodDropDownLabel.Text = 'method';
            app.methodDropDown = uidropdown(app.GridLayout5);
            app.methodDropDown.Items = {'penalty'};
            app.methodDropDown.Value = 'penalty';
            app.steplengthEditFieldLabel = uilabel(app.GridLayout5);
            app.steplengthEditFieldLabel.Text = 'step length';
            app.steplengthEditField = uieditfield(app.GridLayout5, 'numeric');
            app.stepIKEditField = uieditfield(app.GridLayout5, 'numeric');
            app.stepIKEditFieldLabel = uilabel(app.GridLayout5);
            app.stepIKEditFieldLabel.Text = 'step IK';
            app.Tree2 = uitree(app.GridLayout);
            app.Tree2.SelectionChangedFcn = createCallbackFcn(app, @Tree2SelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4_UI_Prototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end